classdef VINGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        A21                    matlab.ui.control.NumericEditField
        A31                    matlab.ui.control.NumericEditField
        A41                    matlab.ui.control.NumericEditField
        A12                    matlab.ui.control.NumericEditField
        A22                    matlab.ui.control.NumericEditField
        A32                    matlab.ui.control.NumericEditField
        A42                    matlab.ui.control.NumericEditField
        A13                    matlab.ui.control.NumericEditField
        A23                    matlab.ui.control.NumericEditField
        A33                    matlab.ui.control.NumericEditField
        A43                    matlab.ui.control.NumericEditField
        A14                    matlab.ui.control.NumericEditField
        A24                    matlab.ui.control.NumericEditField
        A34                    matlab.ui.control.NumericEditField
        A44                    matlab.ui.control.NumericEditField
        PB1                    matlab.ui.control.NumericEditField
        PB2                    matlab.ui.control.NumericEditField
        PB3                    matlab.ui.control.NumericEditField
        PB4                    matlab.ui.control.NumericEditField
        PR4                    matlab.ui.control.NumericEditField
        PR3                    matlab.ui.control.NumericEditField
        PR2                    matlab.ui.control.NumericEditField
        PR1                    matlab.ui.control.NumericEditField
        A11                    matlab.ui.control.NumericEditField
        ParityGeneratorButton  matlab.ui.control.Button
        ParityCheckButton      matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ParityGeneratorButton
        function ParityGeneratorButtonPushed(app, event)
        A1 = [app.A11.Value app.A12.Value app.A13.Value app.A14.Value];
        A2 = [app.A21.Value app.A22.Value app.A23.Value app.A24.Value];
        A3 = [app.A31.Value app.A32.Value app.A33.Value app.A34.Value];
        A4 = [app.A41.Value app.A42.Value app.A43.Value app.A44.Value];
        A = [A1;A2;A3;A4];
        pr = 0;
           for N =1:4
                for B = 1:4
                    pr = pr + A(N,B);
                end
                if (mod(pr,2) ~= 1)
                    A(N,5)=0;
                else
                    A(N,5)=1;
                end
                pr = 0;
            end
            for N =1:4
                pr = 0;
                for B = 1:4
                    pr = pr + A(B,N);
                end
                if (mod(pr,2) ~= 1)
                    A(5,N)=0;
                else 
                    A(5,N)=1;
                end
            end
        app.PR1.Value = A(1,5);
        app.PR2.Value = A(2,5);
        app.PR3.Value = A(3,5);
        app.PR4.Value = A(4,5);
        app.PB1.Value = A(5,1);
        app.PB2.Value = A(5,2);
        app.PB3.Value = A(5,3);
        app.PB4.Value = A(5,4);
                
        end

        % Button pushed function: ParityCheckButton
        function ParityCheckButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.A11 = uieditfield(app.UIFigure, 'numeric');
            app.A21 = uieditfield(app.UIFigure, 'numeric');
            app.A31 = uieditfield(app.UIFigure, 'numeric');
            app.A41 = uieditfield(app.UIFigure, 'numeric');
            app.A12 = uieditfield(app.UIFigure, 'numeric');
            app.A22 = uieditfield(app.UIFigure, 'numeric');
            app.A32 = uieditfield(app.UIFigure, 'numeric');
            app.A42 = uieditfield(app.UIFigure, 'numeric');
            app.A13 = uieditfield(app.UIFigure, 'numeric');
            app.A23 = uieditfield(app.UIFigure, 'numeric');
            app.A33 = uieditfield(app.UIFigure, 'numeric');
            app.A43 = uieditfield(app.UIFigure, 'numeric');
            app.A14 = uieditfield(app.UIFigure, 'numeric');
            app.A24 = uieditfield(app.UIFigure, 'numeric');
            app.A34 = uieditfield(app.UIFigure, 'numeric');
            app.A44 = uieditfield(app.UIFigure, 'numeric');
            app.PB1 = uieditfield(app.UIFigure, 'numeric');
            app.PB2 = uieditfield(app.UIFigure, 'numeric');
            app.PB3 = uieditfield(app.UIFigure, 'numeric');
            app.PB4 = uieditfield(app.UIFigure, 'numeric');
            app.PR4 = uieditfield(app.UIFigure, 'numeric');
            app.PR3 = uieditfield(app.UIFigure, 'numeric');
            app.PR2 = uieditfield(app.UIFigure, 'numeric');
            app.PR1 = uieditfield(app.UIFigure, 'numeric');
            app.ParityGeneratorButton = uibutton(app.UIFigure, 'push');
            app.ParityGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @ParityGeneratorButtonPushed, true);
            app.ParityGeneratorButton.Text = 'Parity Generator';
            app.ParityCheckButton = uibutton(app.UIFigure, 'push');
            app.ParityCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ParityCheckButtonPushed, true);
            app.ParityCheckButton.Text = 'Parity Check';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VINGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end